classdef Image_Figure_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        StartButton               matlab.ui.control.Button
        NextFrameButton           matlab.ui.control.Button
        FolderNameEditFieldLabel  matlab.ui.control.Label
        FolderNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            userInput = app.FolderNameEditField.Value;
            folder_name = userInput(1:10);
            userInput = strcat(folder_name, '/', userInput);
            base_directory = '/mnt/nas_crdataset/';
            search_dir = strcat(base_directory, userInput);
            listing = dir(strcat(base_directory, userInput, '/UNWIN_HEATMAP'));
            folderSize = size(listing, 1)-2;
            disp(folderSize)
            for k = 1:900
                if (k > 900-folderSize)
                    imFile_index = num2str(k-1, '%010d');
                    imFileName = strcat(search_dir, '/images_udst/', imFile_index, '.jpg');
                    A = imread(imFileName);
                    image(app.UIAxes, A);
%                     xlim(app.UIAxes, [0,1450]);
%                     ylim(app.UIAxes, [0,1100]);
                    
                    figFileName = num2str(k, '%06d');
                    figFileName = strcat(search_dir, '/UNWIN_HEATMAP', '/frame_', figFileName, '.png');
                    B = imread(figFileName);
                    image(app.UIAxes2, B);
%                     xlim(app.UIAxes2, [20,780]);
%                     ylim(app.UIAxes2, [0,680]);
                    
                    uiwait();
                end 
            end
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            uiresume();
        end

        % Value changed function: FolderNameEditField
        function FolderNameEditFieldValueChanged(app, event)
            value = app.FolderNameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Radar Heatmap')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.FolderNameEditFieldLabel = uilabel(app.UIFigure);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.UIFigure, 'text');
            app.FolderNameEditField.ValueChangedFcn = createCallbackFcn(app, @FolderNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Figure_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end