classdef Image_Figure_App_Continuous < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        FolderNameEditFieldLabel  matlab.ui.control.Label
        FolderNameEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        MemoryTimer        % Timer object
        counter = 1;
    end

    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            userInput = app.FolderNameEditField.Value;
            seq_name = userInput;
            folder_name = userInput(1:10);
            userInput = strcat(folder_name, '/', userInput);
            base_directory = '/mnt/nas_crdataset/';
            search_dir = strcat(base_directory, userInput);
            listing = dir(strcat(base_directory, userInput, '/UNWIN_HEATMAP'));
            folderSize = size(listing,1) - 2;

            if (app.counter <= 900)
                if (app.counter > 900 - folderSize)
                    imFile_index = num2str(app.counter-1, '%010d');
                    imFileName = strcat(search_dir, '/images_udst/', imFile_index, '.jpg');
                    A = imread(imFileName);
                    image(app.UIAxes, A);
%                     xlim(app.UIAxes, [0,1450]);
%                     ylim(app.UIAxes, [0,1100]);
                    
                    figFileIndex = num2str(app.counter-1, '%06d');
                    figFileName = strcat(search_dir, '/UNWIN_RADAR_LABEL_IMAGE', '/', seq_name, '_', figFileIndex, '.jpg');
                    B = imread(figFileName);
                    B = imresize(B, 5);
                    image(app.UIAxes2, B);
%                     xlim(app.UIAxes2, [20,780]);
%                     ylim(app.UIAxes2, [0,680]);
                end
            else
                app.counter = 0;
            end 
            
            app.counter = app.counter + 10;
            
       end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           xlim(app.UIAxes, [0,1450]);
           ylim(app.UIAxes, [0,1100]);
           
           xlim(app.UIAxes2, [20,780]);
           ylim(app.UIAxes2, [0,680]);
           
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', .5, ...                     % Period is 300 milliseconds
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If timer is not running, start it 
            app.counter = 0;
            if strcmp(app.MemoryTimer.Running, 'off')
               start(app.MemoryTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the timer
%             stop(app.MemoryTimer);
            delete(app.MemoryTimer);
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', .5, ...                     % Period is 300 milliseconds
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.MemoryTimer);
            delete(app.MemoryTimer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memory Usage';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Heatmap')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.FolderNameEditFieldLabel = uilabel(app.UIFigure);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Figure_App_Continuous

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end