classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        StartButton      matlab.ui.control.Button
        NextFrameButton  matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
    end

    % Global Variables
    properties (Access = private)
        
        % constant parameters
        c = physconst('LightSpeed');% Speed of light in air (m/s)
        fc = 77e9;% Center frequency (Hz)
        lambda = physconst('LightSpeed') / 77e9
        Rx = 4;
        Tx = 2;
        
        % file information and frame information
        frame_start = 1;
        frame_end = 6;
        option = 0; % option=0,only plot ang-range; option=1, only generate the synthetic(merged) range-angle heatmap;
            % option=2,only record raw data in format of matrix; option=3,ran+dop+angle estimate;
        IS_Plot_RD = true; % 1 ==> plot the Range-Doppler heatmap 
        IS_SAVE_Data = true;% 1 ==> save range-angle data and heatmap figure
        Is_Det_Static = true;% 1==> detection includes static objects (!!! MUST BE 1 WHEN OPYION = 1)
        Is_Windowed = false;% 1==> Windowing before doing angle fft
        num_stored_figs = 50;% the number of figures that are going to be stored
        cali_n = 3;
        % the number of range bins that need to be calibrated
        neidop_n = 3; % the number of neighbored bins around the selected the doppler 
        
        % generate file name and folder
        file_name = 'adc_data.bin';
        
        % configuration parameters
        Fs = 4*10^6;
        sweepSlope = 21.002e12;
        samples = 128;
        loop = 128;
        Tc = 60e-6; %us
        fft_Rang = 128;
        fft_Vel = 256;
        fft_Ang = 91;
        
        % size of bounding box
        widthRec = 22.5;% degrees ==> pi/8
        heigtRec = 2.5;% meters
       

    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% Creat grid table
            freq_res = app.Fs/app.fft_Rang;% range_grid
            freq_grid = (0:app.fft_Rang-1).'*freq_res;
            rng_grid = freq_grid*app.c/app.sweepSlope/2;% d=frediff_grid*c/sweepSlope/2;
            
            w = [-180:4:180]; % angle_grid
            agl_grid = asin(w/180)*180/pi; % [-1,1]->[-pi/2,pi/2]
            
            % velocity_grid
            dop_grid = fftshiftfreqgrid(app.fft_Vel,1/app.Tc); % now fs is equal to 1/Tc
            vel_grid = dop_grid*app.lambda/2;   % unit: m/s, v = lamda/4*[-fs,fs], dopgrid = [-fs/2,fs/2]
            % vel_grid=3.6*vel_grid;        % unit: km/h
            
            %% read the data file
            eval(['data=readDCA16xx','(app.file_name);'])
            data_all=reshape(data,size(data,1)*size(data,2),[]);  % reshape 4 channels into 1
            data_length=length(data_all);
            data_each_frame=app.samples*app.loop*2*app.Rx;
            Frame_num=data_length/data_each_frame;
            
            caliDcRange_chirp1 = [];
            caliDcRange_chirp2 = [];
            obj_pos = [];
            obj_pos_value = [];
            init_pos = [];
            
            for i=1:1:app.frame_end % 1:end frame, Note:start frame must be 1
                
                % seperate each frame and reshape the raw data to foramt [samples,antennas,chirps]
                Framedata(:,i)=data_all(((i-1)*data_each_frame+1):i*data_each_frame);
                Frame_loop(:,:,i)=reshape(Framedata(:,i),app.samples*4,[]);
                Frame_loop_chirp1(:,:,i)=Frame_loop(:,1:2:end,i);
                Frame_loop_chirp2(:,:,i)=Frame_loop(:,2:2:end,i);
                for jj=1:app.loop
                    Frame_loop_chirp1_Rx(:,:,jj,i)=reshape(Frame_loop_chirp1(:,jj,i),[app.samples app.Rx]);
                    Frame_loop_chirp2_Rx(:,:,jj,i)=reshape(Frame_loop_chirp2(:,jj,i),[app.samples app.Rx]);
                end
                Xcube_chirp1=permute(Frame_loop_chirp1_Rx(:,:,:,i),[1 2 3]);
                Xcube_chirp2=permute(Frame_loop_chirp2_Rx(:,:,:,i),[1 2 3]);
                
                %         % calculate energy (evalution)
                %         cal_Enegry(Xcube_chirp1,Xcube_chirp2,Framedata)
                %         plot_singnal_time(Xcube_chirp1)
                
                if app.option == 0
                    %% plot ang-range and find the location of objects
                    for ip = 1:1
                    if i > app.frame_start-1
                        % Read image, then plot image
                        imfileName = num2str(i-1, '%010d');
                        imfileName = strcat(imfileName, '.jpg');
                        A = imread(imfileName);
                        image(app.UIAxes3, A);
                        xlim(app.UIAxes3, [500, 2500]);
                        ylim(app.UIAxes3, [200, 1800]);
                        
                        % FOR CHIRP 1
                        % Range FFT
                        [Rangedata_chirp1] = fft_range(Xcube_chirp1,app.fft_Rang);
              
                        % caliDcRangeSig
                        [Rangedata_chirp1,caliDcRange_chirp1] = caliDcRangeSig(Rangedata_chirp1,i,app.loop,app.frame_start,caliDcRange_chirp1,app.cali_n);
                        
                        % Check whether to plot range-doppler heatmap
                        if app.IS_Plot_RD
                            % Doppler FFT
                            [Dopdata_chirp1] = fft_doppler(Rangedata_chirp1,app.fft_Vel);
                        
                            % plot range-doppler
                            %plot_rangeDop(Dopdata_chirp1,vel_grid,rng_grid)
                            %figure()
                            mesh(app.UIAxes,vel_grid,rng_grid,abs(squeeze(Dopdata_chirp1(:,1,:))));
                            view(app.UIAxes,0,90)
                            %axis([-10,10,0,28])
                        else
                            
                        end
                       
                        % FOR CHIRP 2
                        % Range FFT
                        [Rangedata_chirp2] = fft_range(Xcube_chirp2,app.fft_Rang);
              
                        % caliDcRangeSig
                        [Rangedata_chirp2,caliDcRange_chirp2] = caliDcRangeSig(Rangedata_chirp2,i,app.loop,app.frame_start,caliDcRange_chirp2,app.cali_n);
                        
                        % Angle FFT
                        % need to do doppler compensation on Rangedata_chirp2 in future
                        Rangedata_merge = [Rangedata_chirp1,Rangedata_chirp2];
                        Angdata = fft_angle(Rangedata_merge,app.fft_Ang,app.Is_Windowed);
                        
                        if i>app.frame_start-1
                            if i < app.frame_start + app.num_stored_figs % plot Range_Angle heatmap
                                %[axh] = plot_rangeAng(Angdata,rng_grid,agl_grid);
                                Nr = size(Angdata,1);   %%%length of Chirp(num of rangeffts)
                                Ne = size(Angdata,2);   %%%number of angleffts
                                Nd = size(Angdata,3);   %%%length of chirp loop
                                
                                % polar coordinates
                                for i_a = 1:size(agl_grid,2)
                                    yvalue(i_a,:) = (sin(agl_grid(i_a)*pi/180 )).*rng_grid;
                                end
                                for i_aa=1:size(agl_grid,2)
                                    xvalue(i_aa,:) = (cos(agl_grid(i_aa)*pi/180)).*rng_grid;
                                end
                                for ix=1:1
                                    Xpow = abs(Angdata).^2;
                                    Xpow=squeeze(Xpow(:,:,ix));
                                
                                    % noisefloor = db2pow(-15);
                                    Xsnr=Xpow;
                                    % Xsnr = pow2db(Xpow/noisefloor);
                                    
                                    
                                    %figure()
                                    %set(app.UIAxes2,'Position',[10,10,530,420])
                                    surf(app.UIAxes2,agl_grid,rng_grid,Xsnr);
                                    view(app.UIAxes2,0,90)
                                    %app.UIAxis2([-50 50 0 25]);
                                    grid(app.UIAxes2,'off')
                                    shading(app.UIAxes2, 'interp')
                                    colorbar(app.UIAxes2)     %caxis([0,0.6])
                                    
                                end
                            end
                            
                            if i == app.frame_start % search the initial position of object
                                cur_pos = find_obj_position(Angdata,init_pos,1,app.Is_Det_Static);
                                init_pos = cur_pos;
                                obj_pos = [obj_pos;i,cur_pos]; % obj_pos list format [frame, range, angle]
                                obj_pos_value = [obj_pos_value;i,rng_grid(cur_pos(1)),agl_grid(cur_pos(2))];
                            else % search the position of object in specific range(temporarily)
                                cur_pos = find_obj_position(Angdata,init_pos,0,app.Is_Det_Static);
                                init_pos = cur_pos;
                                obj_pos = [obj_pos;i,cur_pos]; % obj_pos list format [frame, range, angle]
                                obj_pos_value = [obj_pos_value;i,rng_grid(cur_pos(1)),agl_grid(cur_pos(2))];
                            end
                            
                            if app.IS_SAVE_Data
                                [Angdata] = Normalize(Angdata);
                                % save range-angle heatmap to .mat file
                                %saved_file_name = strcat(saved_folder_name,'/',data_name,'_',num2str(i-app.frame_start,'%06d'),'.mat');
                                %eval(['save(saved_file_name,''Angdata'',''-v6'');'])
                                
                                if i < app.frame_start + app.num_stored_figs % plot rectangle
                                   %posiObjCam = [agl_grid(cur_pos(2))-app.widthRec/2,rng_grid(cur_pos(1))-app.heigtRec/2];
                                   fileName = num2str(i-1, '%010d');
                                   fileName = strcat(fileName, '.txt');
                                   T = readtable(fileName);
                                   for j = 1:2 % reads 2 locations from file and converts to polar 
                                       x = T{j,12};
                                       y = T{j,13};
                                       z = T{j,14};  
                                       range = sqrt(x^2 + y^2 + z^2);
                                       angle = atand(x/z);
                                       posiObjCam = [angle,range];
                                           
                                       hold on
                                       %plot_rectangle(posiObjCam,app.widthRec,app.heigtRec);
                                       maxvalu =1.0e+16*5.0;
                                       posiObjCamX = posiObjCam(1);
                                       posiObjCamY = posiObjCam(2);
                                       xLine = [0:1:99]/100*app.widthRec+posiObjCamX;
                                       yLine = [0:1:99]/100*app.heigtRec+posiObjCamY;
                                       line(app.UIAxes2,xLine,ones(size(xLine))*posiObjCamY,ones(size(xLine))*maxvalu,'Color','r','LineWidth',1)
                                       hold on
                                       line(app.UIAxes2,ones(size(yLine))*posiObjCamX,yLine,ones(size(xLine))*maxvalu,'Color','r','LineWidth',1)
                                       line(app.UIAxes2,xLine,ones(size(xLine))*(posiObjCamY+app.heigtRec),ones(size(xLine))*maxvalu,'Color','r','LineWidth',1)
                                       line(app.UIAxes2,ones(size(yLine))*(posiObjCamX+app.widthRec),yLine,ones(size(xLine))*maxvalu,'Color','r','LineWidth',1)
                                       % save to figure
                                       %saved_fig_file_name = strcat(saved_fig_folder_name,'/','frame_',num2str(i,'%06d'),'.png');
                                       %eval(['saveas(axh,saved_fig_file_name,''png'');'])
                                       %close 
                                   end
                                end
                            end
                        i % print index i  
                        uiwait;
                        end
                    end
                    end
                else
                    
                end
            end         

        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
        uiresume;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Range-doppler plot for Rx1')
            xlabel(app.UIAxes, 'Doppler')
            ylabel(app.UIAxes, 'Range')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Range-Angle Heatmap')
            xlabel(app.UIAxes2, 'Angle of arrive(degrees)')
            ylabel(app.UIAxes2, 'Range(meters)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end